classdef arm_path_visualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DoubleJointArmPathPlanningUIFigure  matlab.ui.Figure
        EndingPointDropDown         matlab.ui.control.DropDown
        EndingPointDropDownLabel    matlab.ui.control.Label
        AnimateButton               matlab.ui.control.StateButton
        SpeedSlider                 matlab.ui.control.Slider
        SpeedSliderLabel            matlab.ui.control.Label
        MovementSlider              matlab.ui.control.Slider
        MovementSliderLabel         matlab.ui.control.Label
        StartingPointDropDown       matlab.ui.control.DropDown
        StartingPointDropDownLabel  matlab.ui.control.Label
        PhaseAxes                   matlab.ui.control.UIAxes
        XZAxes                      matlab.ui.control.UIAxes
    end

    
    properties
        calc;
        paths;
        selected_path;
        shoulder;

        animating = false;
        h_xy_path;
        h_angle_path;
        h_distal;
        h_proximal;
        h_theta;
    end
    
    
    methods (Access = private)
        
        function draw_planned_path(app)
            theta1 = app.selected_path.theta1;
            theta2 = app.selected_path.theta2;

            [elbow.x, elbow.y, tip.x, tip.y] = app.calc.forward_kinematics(theta1, theta2);
            
            if ~isempty(app.h_xy_path{1}), for k=1:length(app.h_xy_path), delete(app.h_xy_path{k}), end, end
            if ~isempty(app.h_angle_path{1}), for k=1:length(app.h_angle_path), delete(app.h_angle_path{k}), end, end
            
            app.h_xy_path{1} = line(app.XZAxes, app.shoulder.x, app.shoulder.y, 'Color', 'g', 'Marker', 'x');
            app.h_xy_path{2} = line(app.XZAxes, elbow.x, elbow.y, 'Color', 'b', 'Linestyle', '--');
            app.h_xy_path{3} = line(app.XZAxes, elbow.x(1), elbow.y(1), 'Color', 'b', 'Marker', 'x');
            app.h_xy_path{4} = line(app.XZAxes, elbow.x(end), elbow.y(end), 'Color', 'b', 'Marker', 'x');
            app.h_xy_path{5} = line(app.XZAxes, tip.x, tip.y, 'Color', 'r', 'Linestyle', '--');
            app.h_xy_path{6} = line(app.XZAxes, tip.x(1), tip.y(1), 'Color', 'r', 'Marker', 'x');
            app.h_xy_path{7} = line(app.XZAxes, tip.x(end), tip.y(end), 'Color', 'r', 'Marker', 'x');
            
            app.h_angle_path{1} = line(app.PhaseAxes, theta1, theta2, 'Color', 'r', 'Linestyle', '--');
            app.h_angle_path{2} = line(app.PhaseAxes, theta1(1), theta2(1), 'Color', 'r', 'Marker', 'x');
            app.h_angle_path{3} = line(app.PhaseAxes, theta1(end), theta2(end), 'Color', 'r', 'Marker', 'x');
        end
        
        function draw_current_state(app, t)
            [~,idx] = min(abs(t-app.selected_path.t));

            theta1 = app.selected_path.theta1(idx);
            theta2 = app.selected_path.theta2(idx);


            [elbow.x, elbow.y, tip.x, tip.y] = app.calc.forward_kinematics(theta1, theta2);
            
            if ~isempty(app.h_distal), delete(app.h_distal), delete(app.h_proximal); delete(app.h_theta); end
            app.h_distal = line(app.XZAxes, [app.shoulder.x elbow.x], [app.shoulder.y elbow.y], 'Color', 'b', 'LineWidth', 5);
            app.h_proximal = line(app.XZAxes, [elbow.x tip.x], [elbow.y tip.y], 'Color', 'r', 'LineWidth', 5);
            app.h_theta = line(app.PhaseAxes, theta1, theta2, 'Color','r', 'Marker', 'o');
            drawnow;
        end
        
        function change_path(app)
            start_idx = app.StartingPointDropDown.Value;
            end_idx = app.EndingPointDropDown.Value;
            
            app.selected_path = app.paths(start_idx, end_idx);
            app.draw_planned_path();
            app.draw_current_state(0);

            if app.selected_path.totalTime == 0
                app.MovementSlider.Enable = false;
                app.MovementSlider.Limits = [0 100];
            else
                app.MovementSlider.Enable = true;
                app.MovementSlider.Limits = [0 app.selected_path.totalTime];
            end
        end
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.animating = false;
            
            s = jsondecode(fileread('..\..\src\main\deploy\arm_config.json'));
            arm_length(1) = s.proximal.length * 39.3701;
            arm_length(2) = s.distal.length * 39.3701;
            app.shoulder.x = s.shoulder(1) * 39.3701;
            app.shoulder.y = s.shoulder(2) * 39.3701;
            bumper_width_inches = s.bumper_width_inches;

            app.calc = arm_kinematics(app.shoulder.x, app.shoulder.y, arm_length);

            margin.x = 3;
            margin.y = 3;
            intake_margin.x = 3;
            intake_margin.y = 3;
            
            field = get_field_positions(bumper_width_inches);
            [obstacles, obstacleEnum] = get_obstacles(bumper_width_inches, field, margin, intake_margin, app.calc);            
            
            presetEnum = get_preset_enum();
            preset_names = fieldnames(presetEnum);
            
            plot_xy_space(app.XZAxes, field, obstacles, obstacleEnum);
            plot_angle_space(app.PhaseAxes, field, obstacles, obstacleEnum);
            app.h_xy_path{1} = [];
            app.h_angle_path{1} = [];


            for start_idx = presetEnum.DEFENSE:presetEnum.SCORE_HIGH_CONE
                for final_idx = presetEnum.DEFENSE:presetEnum.SCORE_HIGH_CONE
                    filename = sprintf('arm_path_%d_%d.json', start_idx-1, final_idx-1);
                    s = jsondecode(fileread(['..\..\src\main\deploy\paths\' filename]));
                    app.paths(start_idx, final_idx).totalTime = max(s.totalTime, 0);
                    app.paths(start_idx, final_idx).theta1 = rad2deg(s.theta1);
                    app.paths(start_idx, final_idx).theta2 = rad2deg(s.theta1 + s.theta2);
                    app.paths(start_idx, final_idx).t = linspace(0, app.paths(start_idx, final_idx).totalTime, length(app.paths(start_idx, final_idx).theta1));
                end
            end


            for k = 1:presetEnum.SCORE_HIGH_CONE
                app.StartingPointDropDown.Items{k} = preset_names{k};
                app.StartingPointDropDown.ItemsData(k) = k;
                app.EndingPointDropDown.Items{k} = preset_names{k};
                app.EndingPointDropDown.ItemsData(k) = k;
            end
            
            app.StartingPointDropDown.Value = app.StartingPointDropDown.ItemsData(1);
            app.EndingPointDropDown.Value = app.EndingPointDropDown.ItemsData(end);
            app.StartingPointDropDownValueChanged();
            app.EndingPointDropDownValueChanged();

        end

        % Value changed function: StartingPointDropDown
        function StartingPointDropDownValueChanged(app, event)
            app.change_path();
        end

        % Value changed function: AnimateButton
        function AnimateButtonValueChanged(app, event)
            value = app.AnimateButton.Value;
            
            t_start = tic;
            t_previous = toc(t_start);
            t_elapsed = 0;
            
            % run this loop while the button is still down
            while 1
                if ~app.AnimateButton.Value
                    break
                end
                
                if ~app.animating
                    app.animating = true;
                    t_start = tic;
                    t_elapsed = 0;
                end
                
                t_current = toc(t_start);
                dt = (t_current - t_previous) * app.SpeedSlider.Value/100;
                dt = max(dt, 0);
                t_previous = t_current;
                
                t_elapsed = t_elapsed + dt;
                t_elapsed = min(t_elapsed, app.selected_path.totalTime);
                
                app.draw_current_state(t_elapsed);

                if t_elapsed >= app.selected_path.totalTime
                    app.animating = false;
                    app.AnimateButton.Value = false;
                    break
                end

                if app.animating
                    app.MovementSlider.Value = t_elapsed;
                    app.MovementSliderValueChanged(event);
                    pause(0.01);
                end
            end  

            app.animating = false;
        end

        % Value changing function: MovementSlider
        function MovementSliderValueChanging(app, event)
            t = event.Value;
            app.draw_current_state(t);
        end

        % Value changed function: EndingPointDropDown
        function EndingPointDropDownValueChanged(app, event)
            app.change_path();
        end

        % Value changed function: MovementSlider
        function MovementSliderValueChanged(app, event)
            t = app.MovementSlider.Value;
            app.draw_current_state(t);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DoubleJointArmPathPlanningUIFigure = uifigure('Visible', 'off');
            app.DoubleJointArmPathPlanningUIFigure.Name = 'Double Joint Arm Path Planning';
            app.XZAxes = uiaxes(app.DoubleJointArmPathPlanningUIFigure);
            title(app.XZAxes, 'Cartesian')
            xlabel(app.XZAxes, 'Distance from Center of Robot (in)')
            ylabel(app.XZAxes, 'Height above Floor (in)')
            app.PhaseAxes = uiaxes(app.DoubleJointArmPathPlanningUIFigure);
            title(app.PhaseAxes, 'Angle Space')
            xlabel(app.PhaseAxes, '\theta_1 (rad)')
            ylabel(app.PhaseAxes, '\theta_{21} (rad)')
            app.StartingPointDropDownLabel = uilabel(app.DoubleJointArmPathPlanningUIFigure);
            app.StartingPointDropDownLabel.Text = 'Starting Point';
            app.StartingPointDropDown = uidropdown(app.DoubleJointArmPathPlanningUIFigure);
            app.StartingPointDropDown.Items = {};
            app.StartingPointDropDown.ValueChangedFcn = createCallbackFcn(app, @StartingPointDropDownValueChanged, true);
            app.StartingPointDropDown.Value = {};
            app.MovementSliderLabel = uilabel(app.DoubleJointArmPathPlanningUIFigure);
            app.MovementSliderLabel.Text = 'Movement';
            app.MovementSlider = uislider(app.DoubleJointArmPathPlanningUIFigure);
            app.MovementSlider.ValueChangedFcn = createCallbackFcn(app, @MovementSliderValueChanged, true);
            app.MovementSlider.ValueChangingFcn = createCallbackFcn(app, @MovementSliderValueChanging, true);
            app.SpeedSliderLabel = uilabel(app.DoubleJointArmPathPlanningUIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.DoubleJointArmPathPlanningUIFigure);
            app.SpeedSlider.Value = 100;
            app.AnimateButton = uibutton(app.DoubleJointArmPathPlanningUIFigure, 'state');
            app.AnimateButton.ValueChangedFcn = createCallbackFcn(app, @AnimateButtonValueChanged, true);
            app.AnimateButton.Text = 'Animate';
            app.EndingPointDropDownLabel = uilabel(app.DoubleJointArmPathPlanningUIFigure);
            app.EndingPointDropDownLabel.Text = 'Ending Point';
            app.EndingPointDropDown = uidropdown(app.DoubleJointArmPathPlanningUIFigure);
            app.EndingPointDropDown.Items = {};
            app.EndingPointDropDown.ValueChangedFcn = createCallbackFcn(app, @EndingPointDropDownValueChanged, true);
            app.EndingPointDropDown.Value = {};
            % Show the figure after all components are created
            app.DoubleJointArmPathPlanningUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arm_path_visualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DoubleJointArmPathPlanningUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DoubleJointArmPathPlanningUIFigure)
        end
    end
end